classdef initialize_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        GridLayout                  matlab.ui.container.GridLayout
        LeftPanel                   matlab.ui.container.Panel
        logPerturbationSlider       matlab.ui.control.Slider
        logPerturbationSliderLabel  matlab.ui.control.Label
        StatePanel                  matlab.ui.container.Panel
        InitialvalueEditField       matlab.ui.control.NumericEditField
        InitialvalueEditFieldLabel  matlab.ui.control.Label
        StateListBox                matlab.ui.control.ListBox
        ParameterPanel              matlab.ui.container.Panel
        UpperboundEditField         matlab.ui.control.NumericEditField
        UpperboundEditFieldLabel    matlab.ui.control.Label
        LowerboundEditField         matlab.ui.control.NumericEditField
        LowerboundEditFieldLabel    matlab.ui.control.Label
        ParameterListBox            matlab.ui.control.ListBox
        MultistartsSpinner          matlab.ui.control.Spinner
        MultistartsSpinnerLabel     matlab.ui.control.Label
        CellsSlider                 matlab.ui.control.Slider
        CellsSliderLabel            matlab.ui.control.Label
        StatesListBox               matlab.ui.control.ListBox
        StatesListBoxLabel          matlab.ui.control.Label
        FinishButton                matlab.ui.control.Button
        LiveCheckBox                matlab.ui.control.CheckBox
        FitButton                   matlab.ui.control.Button
        ParUIAxes                   matlab.ui.control.UIAxes
        RightPanel                  matlab.ui.container.Panel
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = public)
        fs
        tl
        lastplots
        active
        
        active_parameter
        active_state
    end
    
    methods (Access = private)
        function plot_fitted(app)
            for idx = 1:numel(app.lastplots), delete(app.lastplots(idx)), end
            
            n_cells = app.CellsSlider.Value;
            state_indices = find(app.active);
            for idx = 1:length(state_indices)
                ax = nexttile(app.tl, idx);
                app.lastplots(1:n_cells, idx) = plot(ax, app.fs.data.t, ...
                                                     reshape(app.fs.fitted_fs(:, state_indices(idx), 1:n_cells), ...
                                                             app.fs.data.T, []), ...
                                                     '--', 'LineWidth', 1.25);
                set(app.lastplots(1:n_cells, idx), {'color'}, num2cell(turbo(n_cells), 2));
                title(ax, app.fs.system.states(state_indices(idx)))
                xlabel(ax, 'time (min)')
                ylabel(ax, 'value (AU)')
            end
            
            estimates = app.fs.beta_fs_init(1, :);
            scales = floor(log10(estimates))+1;
            scaled = estimates .* 10.^-scales;
            labels = app.fs.system.parameters_variable;
            scale_labels = strings(1, length(scales));
            for idx = 1:length(scale_labels)
                if scales(idx) ~= 0, scale_labels(idx) = sprintf("×1E%d", scales(idx)); end
            end
            hB = bar(app.ParUIAxes, categorical(labels), scaled);
            hT = [];
            for i=1:length(hB)
                hT = [hT text(app.ParUIAxes, hB(i).XData, hB(i).YData, scale_labels, ...
                              'VerticalAlignment','bottom','horizontalalign','center')];
            end
        end
        
        function setup_plots(app)
            nplots = sum(app.active);
            ncols = ceil(sqrt(nplots));
            nrows = ceil(nplots / ncols);
            delete(app.tl)
            app.tl = tiledlayout(app.RightPanel, nrows, ncols);
            
            n_cells = app.CellsSlider.Value;
            state_indices = find(app.active);
            for idx = 1:nplots
                ax = nexttile(app.tl, idx);
                hold(ax, 'on')
                if ismember(state_indices(idx), app.fs.data.observed)
                    h = plot(ax, app.fs.data.t_data, ...
                             reshape(app.fs.data.traces(:, find(state_indices(idx) == app.fs.data.observed), 1:n_cells), ...
                                     app.fs.data.T_data, []));
                    set(h, {'color'}, num2cell(turbo(n_cells), 2));
                end
                title(ax, app.fs.system.states(state_indices(idx)))
                xlabel(ax, 'time (min)')
                ylabel(ax, 'value (AU)')
            end
        end
        
        function refresh(app, update)
            if nargin == 1, update = false; end
            if update || app.LiveCheckBox.Value
                app.setup_plots()
                app.fs.initialize()
                app.fs.integrate()
                app.plot_fitted()
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, fs)
            app.fs = fs;
            
            N = fs.data.N;
            nplots = min(12, fs.system.K);
            app.lastplots = gobjects(N, nplots);
            app.active = false(1, fs.system.K);
            app.active(1:nplots) = true;
            
            app.FitButton.Enable = false;
            app.StatesListBox.Items = convertStringsToChars(fs.system.states)';
            app.StatesListBox.Value = app.StatesListBox.Items(1:nplots);
            
            app.StateListBox.Items = convertStringsToChars(fs.system.states);
            app.StateListBox.Value = app.StateListBox.Items(1);
            app.active_state = 1;
            app.InitialvalueEditField.Value = fs.data.init(1);
            
            parListItems = convertStringsToChars(fs.system.parameters_variable);
            if fs.system.P == 1, parListItems = {parListItems}; end
            app.ParameterListBox.Items = parListItems;
            app.ParameterListBox.Value = app.ParameterListBox.Items(1);
            app.active_parameter = 1;
            app.LowerboundEditField.Value = fs.settings.lb(1);
            app.UpperboundEditField.Value = fs.settings.ub(1);
            
            app.fs.settings.perturbation = 10^app.logPerturbationSlider.Value;
            
            app.MultistartsSpinner.Value = fs.settings.nstart;
            
            app.CellsSlider.Limits = [1 N];
            app.CellsSlider.Value = min(20, N);
            
            steps = [1 2 5 10 25 50 100 250 500 1000 2500 5000 10000];
            [~, minIdx] = min(abs(N/5 - steps));
            majorsize = steps(minIdx);
            minorsize = ceil(majorsize/5);
            app.CellsSlider.MajorTicksMode = 'manual';
            app.CellsSlider.MajorTicks = [1 majorsize : majorsize : N-mod(N,ceil(majorsize/2))-ceil(majorsize/2) N];
            app.CellsSlider.MinorTicksMode = 'manual';
            app.CellsSlider.MinorTicks = [1 minorsize : minorsize : N-minorsize];
            
            app.refresh(true)
        end

        % Button pushed function: FitButton
        function FitButtonPushed(app, event)
            app.refresh(true)
        end

        % Callback function
        function logSliderValueChanged(app, event)
%             value = app.logSlider.Value;
%             [~, minIdx] = min(abs(value - event.Source.MinorTicks(:)));
%             event.Source.Value = event.Source.MinorTicks(minIdx);
% %             app.OrderSlider.Value = event.Source.MinorTicks(minIdx);
%             if app.LiveCheckBox.Value
%                 app.smooth()
%             end
        end

        % Button pushed function: FinishButton
        function FinishButtonPushed(app, event)
            app.FinishButton.UserData = 1;
        end

        % Value changed function: LiveCheckBox
        function LiveCheckBoxValueChanged(app, event)
            app.FitButton.Enable = ~app.LiveCheckBox.Value;
        end

        % Value changed function: StatesListBox
        function StatesListBoxValueChanged(app, event)
            app.active = ismember(app.StatesListBox.Items, app.StatesListBox.Value);
            app.setup_plots()
            app.plot_fitted()
        end

        % Value changed function: CellsSlider
        function CellsSliderValueChanged(app, event)
            event.Source.Value = round(app.CellsSlider.Value);
            app.setup_plots()
            app.plot_fitted()
        end

        % Value changed function: logPerturbationSlider
        function logPerturbationSliderValueChanged(app, event)
            value = app.logPerturbationSlider.Value;
            app.fs.settings.perturbation = (value > -2.9) * 10^value;
            app.fs.perturb();
            app.fs.integrate();
            app.plot_fitted();
        end

        % Value changed function: StateListBox
        function StateListBoxValueChanged(app, event)
            app.active_state = find(app.StateListBox.Value == string(app.StateListBox.Items));
            disp(app.active_state)
            app.InitialvalueEditField.Value = app.fs.data.init(app.active_state);
        end

        % Value changed function: ParameterListBox
        function ParameterParameterListBoxValueChanged(app, event)
            app.active_parameter = find(app.ParameterListBox.Value == string(app.ParameterListBox.Items));
            disp(app.active_parameter)
            app.LowerboundEditField.Value = app.fs.settings.lb(app.active_parameter);
            app.UpperboundEditField.Value = app.fs.settings.ub(app.active_parameter);
        end

        % Value changed function: InitialvalueEditField
        function InitialvalueEditFieldValueChanged(app, event)
            app.fs.data.init(app.active_state) = app.InitialvalueEditField.Value;
            app.refresh();
        end

        % Value changed function: LowerboundEditField
        function LowerboundEditFieldValueChanged(app, event)
            app.fs.settings.lb(app.active_parameter) = app.LowerboundEditField.Value;
            app.refresh();
        end

        % Value changed function: UpperboundEditField
        function UpperboundEditFieldValueChanged(app, event)
            app.fs.settings.ub(app.active_parameter) = app.UpperboundEditField.Value;
            app.refresh();
        end

        % Value changed function: MultistartsSpinner
        function MultistartsSpinnerValueChanged(app, event)
            app.fs.settings.nstart = app.MultistartsSpinner.Value;
            app.refresh();
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {900, 900};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {325, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.ParUIAxes = uiaxes(app.LeftPanel);
            title(app.ParUIAxes, 'Parameter estimate', 'Interpreter', 'latex')
            app.FitButton = uibutton(app.LeftPanel, 'push');
            app.FitButton.ButtonPushedFcn = createCallbackFcn(app, @FitButtonPushed, true);
            app.FitButton.Text = 'Fit';
            app.LiveCheckBox = uicheckbox(app.LeftPanel);
            app.LiveCheckBox.ValueChangedFcn = createCallbackFcn(app, @LiveCheckBoxValueChanged, true);
            app.LiveCheckBox.Text = 'Live';
            app.LiveCheckBox.Value = true;
            app.FinishButton = uibutton(app.LeftPanel, 'push');
            app.FinishButton.ButtonPushedFcn = createCallbackFcn(app, @FinishButtonPushed, true);
            app.FinishButton.Text = 'Finish';
            app.StatesListBoxLabel = uilabel(app.LeftPanel);
            app.StatesListBoxLabel.Text = 'States';
            app.StatesListBox = uilistbox(app.LeftPanel);
            app.StatesListBox.Items = {'a', 'b'};
            app.StatesListBox.Multiselect = 'on';
            app.StatesListBox.ValueChangedFcn = createCallbackFcn(app, @StatesListBoxValueChanged, true);
            app.StatesListBox.Value = {};
            app.CellsSliderLabel = uilabel(app.LeftPanel);
            app.CellsSliderLabel.Text = 'Cells';
            app.CellsSlider = uislider(app.LeftPanel);
            app.CellsSlider.ValueChangedFcn = createCallbackFcn(app, @CellsSliderValueChanged, true);
            app.MultistartsSpinnerLabel = uilabel(app.LeftPanel);
            app.MultistartsSpinnerLabel.Text = 'Multistarts';
            app.MultistartsSpinner = uispinner(app.LeftPanel);
            app.MultistartsSpinner.Limits = [1 100];
            app.MultistartsSpinner.ValueChangedFcn = createCallbackFcn(app, @MultistartsSpinnerValueChanged, true);
            app.MultistartsSpinner.Value = 10;
            app.ParameterPanel = uipanel(app.LeftPanel);
            app.ParameterPanel.Title = 'Parameter';
            app.ParameterListBox = uilistbox(app.ParameterPanel);
            app.ParameterListBox.ValueChangedFcn = createCallbackFcn(app, @ParameterParameterListBoxValueChanged, true);
            app.LowerboundEditFieldLabel = uilabel(app.ParameterPanel);
            app.LowerboundEditFieldLabel.Text = 'Lower bound';
            app.LowerboundEditField = uieditfield(app.ParameterPanel, 'numeric');
            app.LowerboundEditField.ValueChangedFcn = createCallbackFcn(app, @LowerboundEditFieldValueChanged, true);
            app.LowerboundEditField.Value = 0.1;
            app.UpperboundEditFieldLabel = uilabel(app.ParameterPanel);
            app.UpperboundEditFieldLabel.Text = 'Upper bound';
            app.UpperboundEditField = uieditfield(app.ParameterPanel, 'numeric');
            app.UpperboundEditField.ValueChangedFcn = createCallbackFcn(app, @UpperboundEditFieldValueChanged, true);
            app.UpperboundEditField.Value = 10;
            app.StatePanel = uipanel(app.LeftPanel);
            app.StatePanel.Title = 'State';
            app.StateListBox = uilistbox(app.StatePanel);
            app.StateListBox.ValueChangedFcn = createCallbackFcn(app, @StateListBoxValueChanged, true);
            app.InitialvalueEditFieldLabel = uilabel(app.StatePanel);
            app.InitialvalueEditFieldLabel.Text = 'Initial value';
            app.InitialvalueEditField = uieditfield(app.StatePanel, 'numeric');
            app.InitialvalueEditField.ValueChangedFcn = createCallbackFcn(app, @InitialvalueEditFieldValueChanged, true);
            app.logPerturbationSliderLabel = uilabel(app.LeftPanel);
            app.logPerturbationSliderLabel.Text = 'log Perturbation';
            app.logPerturbationSlider = uislider(app.LeftPanel);
            app.logPerturbationSlider.Limits = [-3 1];
            app.logPerturbationSlider.MajorTicks = [-3 -2 -1 0 1];
            app.logPerturbationSlider.MajorTickLabels = {'0', '.01', '.1', '1', '10', '100'};
            app.logPerturbationSlider.ValueChangedFcn = createCallbackFcn(app, @logPerturbationSliderValueChanged, true);
            app.logPerturbationSlider.MinorTicks = [-3 -2.39794000867204 -2.15490195998574 -2 -1.39794000867204 -1.15490195998574 -1 -0.397940008672038 -0.154901959985743 0 0.602059991327962 0.845098040014257 1];
            app.logPerturbationSlider.Value = -1;
            app.RightPanel = uipanel(app.GridLayout);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = initialize_app(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end