classdef smoothing_new < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        GridLayout                 matlab.ui.container.GridLayout
        LeftPanel                  matlab.ui.container.Panel
        ManualknotselectorLabel    matlab.ui.control.Label
        AutomaticButton            matlab.ui.control.Button
        StatesDropDown             matlab.ui.control.DropDown
        StatesDropDownLabel        matlab.ui.control.Label
        CellsSlider                matlab.ui.control.Slider
        CellstodisplaySliderLabel  matlab.ui.control.Label
        FinishButton               matlab.ui.control.Button
        KnotsSlider                matlab.ui.control.Slider
        KnotsLabel                 matlab.ui.control.Label
        LiveCheckBox               matlab.ui.control.CheckBox
        SmoothButton               matlab.ui.control.Button
        KnotsSelectorUIAxes        matlab.ui.control.UIAxes
        RightPanel                 matlab.ui.container.Panel
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    properties (Access = private)
        smoother
        knots
        active
        state_names
        cells_sorted
        
        N
        L
        T
        t
        t_fine
        
        tl
        lastplots
    end
    
    methods (Access = private)
        function smooth(app)                        % Reestimate B-spline smoothing
            app.smoother.optimize(app.active)
        end
        
        
        function update_knot_adjusters(app)         % Update knots in smoother, slider, and selector
            knots_full = app.knots;                     % add endpoints, update B-spline objects, and reset coefficients
            for k = 1:app.L, knots_full{k} = [0 app.knots{k} 1]; end
            app.smoother.update_knots(knots_full, app.active);      % 
            
            if length(app.active) == 1                  % display first state if set to [All]
                displayed = app.active;
            else
                displayed = 1;
            end
                                                        % update knots slider
            app.KnotsSlider.Value = length(app.knots{displayed});
            
            B = app.smoother.B_fine{displayed};         % update B-spline basis plot
            plot(app.KnotsSelectorUIAxes, app.t_fine, B, 'HitTest', 'off')
            xlim(app.KnotsSelectorUIAxes, [min(app.t) max(app.t)])
            ylim(app.KnotsSelectorUIAxes, [0 1])
            
            if ~isempty(app.knots{displayed})     % add xlines at interior knots, if any
                hold(app.KnotsSelectorUIAxes, 'on')
                xline(app.KnotsSelectorUIAxes, app.knots{displayed} * range(app.t) + app.t(1), 'k--', 'HitTest', 'off')
                hold(app.KnotsSelectorUIAxes, 'off')
            end
        end
        
        
        function setup_plots(app)                   % Create (tiled)layout with plotted measurements
            delete(app.tl)
            if length(app.active) == 1
                ncols = 1;
                nrows = 1;
            else
                ncols = ceil(sqrt(app.L));
                nrows = ceil(app.L / ncols);
            end                                         % refresh tiled layout
            app.tl = tiledlayout(app.RightPanel, nrows, ncols);
            
            n_cells = round(exp(app.CellsSlider.Value));
            for k = app.active                          % plot data of active state(s)
                ax = nexttile(app.tl, min(k, length(app.active)));
                hold(ax, 'on')
                traces_k = app.smoother.data.traces(:, k, app.cells_sorted(1:n_cells));
                h = plot(ax, app.t, reshape(traces_k, length(app.t), []));
                
                set(h, {'color'}, num2cell(turbo(n_cells), 2));
                title(ax, app.state_names{k})
                xlabel(ax, 'time (min)')
                ylabel(ax, 'value (AU)')
            end
        end
        
        
        function plot_smoothed(app)                 % Update plotted smoothed measurements
                                                        % delete previous smoothings
            for idx = 1:numel(app.lastplots), delete(app.lastplots(idx)), end
            
            n_cells = round(exp(app.CellsSlider.Value));
            for k = app.active                          % plot updated smoothed measurement
                ax = nexttile(app.tl, min(k, length(app.active)));
                smoothed_k = reshape(app.smoother.data.smoothed_fine(:, k, app.cells_sorted(1:n_cells)), length(app.t_fine), []);
                app.lastplots(1:n_cells, k) = plot(ax, app.t_fine, smoothed_k, '--', 'LineWidth', 1.25);
                set(app.lastplots(1:n_cells, k), {'color'}, num2cell(turbo(n_cells), 2));
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, smoother)
            app.smoother = smoother;
            [app.T, app.L, app.N] = size(smoother.data.traces);
            app.t = smoother.data.t;
            app.t_fine = (app.smoother.scaled_fine + app.t(1)) * range(app.t);
            
                                                        % set state selector and active states to [All]
            app.state_names = convertStringsToChars(smoother.system.states(smoother.data.observed));
            if length(smoother.data.observed) == 1
                app.state_names = {app.state_names};
            end
            app.StatesDropDown.Items = ['All'; app.state_names];
            app.StatesDropDown.Value = 'All';
            app.active = 1:app.L;
            
            app.knots = cell(1, app.L);                 % copy interior knots from smoother
            for k = 1:app.L
                interior_knots = ~ismember(smoother.settings.knots{k}, [0 1]);
                app.knots{k} = smoother.settings.knots{k}(interior_knots);
            end
            app.KnotsSlider.Limits = [0 app.T-smoother.settings.order];
            app.update_knot_adjusters()                 % setup knots slider and selectos
            
            app.CellsSlider.Limits = log([1 app.N]);         % adjust cells slider to data size
            app.CellsSlider.Value = log(min(20, app.N));
                                                        % scale slider ticks according to data size
%             steps = [1 2 5 10 25 50 100 250 500 1000 2500 5000 10000];
%             [~, minIdx] = min(abs(app.N/5 - steps));
%             majorsize = steps(minIdx);
%             minorsize = ceil(majorsize/5);
%             app.CellsSlider.MajorTicksMode = 'manual';
%             app.CellsSlider.MajorTicks = log([1 majorsize : majorsize : app.N-mod(app.N,ceil(majorsize/2))-ceil(majorsize/2) app.N]);
%             app.CellsSlider.MajorTickLabels = convertStringsToChars(string([1 majorsize : majorsize : app.N-mod(app.N,ceil(majorsize/2))-ceil(majorsize/2) app.N]));
%             app.CellsSlider.MinorTicksMode = 'manual';
%             app.CellsSlider.MinorTicks = log([1 minorsize : minorsize : app.N-minorsize]);
            
            major = [1 5 10 50 100 500 1000 5000 10000];
            minor = [1:10 20:10:100 200:100:1000 2000:1000:10000];
            app.CellsSlider.MajorTicksMode = 'manual';
            app.CellsSlider.MajorTicks = log(unique(min(major, app.N)));
            app.CellsSlider.MajorTickLabels = convertStringsToChars(string(unique(min(major, app.N))));
            app.CellsSlider.MinorTicksMode = 'manual';
            app.CellsSlider.MinorTicks = log(unique(min(minor, app.N)));
            
            mean_trace = mean(smoother.data.traces, 3); % order cells to be displayed
            state_scales = mean(smoother.data.traces.^2, [1 3]);
            dist_traces = mean((smoother.data.traces - mean_trace).^2 ./ state_scales, [1 2]);
            [~, order] = sort(reshape(dist_traces, 1, []));
            app.cells_sorted = [order(1) order(end:-1:2)];
            
            app.SmoothButton.Enable = false;            % disable starting with live smoothing
            
            app.lastplots = gobjects(app.N, app.L);     % initialize smoothed measurement plots
            
            app.smooth()                                % optimize using initial knots
            app.setup_plots()                           % setup (tiled)layout with measurement plots
            app.plot_smoothed()                         % plot smoothed measurements
        end

        % Value changed function: StatesDropDown
        function StateValueChanged(app, event)
            value = app.StatesDropDown.Value;
            if string(app.StatesDropDown.Value) == "All"% set active state indices
                app.active = 1:app.L;
            else
                app.active = find(strcmp(value, app.state_names));
            end
            
            app.setup_plots()
            app.plot_smoothed()
            app.update_knot_adjusters()
        end

        % Button pushed function: AutomaticButton
        function AutomaticButtonPushed(app, event)
            app.smoother.place_knots()                  % update smoother knots
            for k = app.active                          % copy knots to app for active state(s)
                knots_k = app.smoother.settings.knots{k};
                app.knots{k} = knots_k(~ismember(knots_k, [0 1]));
            end
            
            app.update_knot_adjusters()
            if app.LiveCheckBox.Value
                app.smooth()
                app.plot_smoothed()
            end
        end

        % Value changed function: KnotsSlider
        function KnotsSliderValueChanged(app, event)
                                                        % correct noninteger numbers of knots
            event.Source.Value = round(app.KnotsSlider.Value);
            
            for k = app.active                          % overridewith equidistant knots for active state(s)
                app.knots{k} = linspace(0, 1, app.KnotsSlider.Value+2);
                app.knots{k} = app.knots{k}(2:end-1);
            end
            
            app.update_knot_adjusters()
            if app.LiveCheckBox.Value
                app.smooth()
                app.plot_smoothed()
            end
        end

        % Button down function: KnotsSelectorUIAxes
        function KnotsSelectorButtonDown(app, event)
                                                        % display first state if set to [All]
            if length(app.active) > 1, k = 1; else, k = app.active; end
            
                                                        % find mouse location relative to axes
            x = app.KnotsSelectorUIAxes.CurrentPoint(1, 1);
            knots_rescaled = app.knots{k} * range(app.t) + app.t(1);
            x_normalized = (x - app.t(1)) / range(app.t);
            
            if strcmp(app.UIFigure.SelectionType, 'alt')% remove/add for alt/other mouse action
                if length(app.knots{k}) > 1             % remove closest to mouse location
                    distances = abs(x - knots_rescaled);
                    app.knots{k} = app.knots{k}(distances > min(distances));
                else
                    app.knots{k} = [];
                end                                     % add knot at mouse location
            elseif length(app.knots{k}) < app.T - app.smoother.settings.order
                app.knots{k} = [app.knots{k} x_normalized];
            end
            
            app.knots{k} = sort(app.knots{k});
            if length(app.active) > 1                   % update subsequent states if set to [All]
                for k = 2:length(app.active), app.knots{k} = app.knots{1}; end
            end
            
            app.update_knot_adjusters()
            if app.LiveCheckBox.Value
                app.smooth()
                app.plot_smoothed()
            end
        end

        % Value changed function: CellsSlider
        function CellsSliderValueChanged(app, event)
            event.Source.Value = log(round(exp(app.CellsSlider.Value)));
            app.setup_plots()
            app.plot_smoothed()
        end

        % Button pushed function: SmoothButton
        function SmoothButtonPushed(app, event)
            app.smooth()
            app.plot_smoothed()
        end

        % Value changed function: LiveCheckBox
        function LiveValueChanged(app, event)
            app.SmoothButton.Enable = ~app.LiveCheckBox.Value;
            if app.LiveCheckBox.Value
                app.smooth()
            end
        end

        % Button pushed function: FinishButton
        function FinishButtonPushed(app, event)
            app.FinishButton.UserData = 1;
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {900, 900};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {325, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.KnotsSelectorUIAxes = uiaxes(app.LeftPanel);
            xlabel(app.KnotsSelectorUIAxes, 'time', 'Interpreter', 'latex')
            app.KnotsSelectorUIAxes.ButtonDownFcn = createCallbackFcn(app, @KnotsSelectorButtonDown, true);
            app.SmoothButton = uibutton(app.LeftPanel, 'push');
            app.SmoothButton.ButtonPushedFcn = createCallbackFcn(app, @SmoothButtonPushed, true);
            app.SmoothButton.Text = 'Smooth';
            app.LiveCheckBox = uicheckbox(app.LeftPanel);
            app.LiveCheckBox.ValueChangedFcn = createCallbackFcn(app, @LiveValueChanged, true);
            app.LiveCheckBox.Text = 'Live';
            app.LiveCheckBox.Value = true;
            app.KnotsLabel = uilabel(app.LeftPanel);
            app.KnotsLabel.Text = '#Knots';
            app.KnotsSlider = uislider(app.LeftPanel);
            app.KnotsSlider.Limits = [0 40];
            app.KnotsSlider.MajorTicks = [0 5 10 15 20 25 30 35 40];
            app.KnotsSlider.MajorTickLabels = {'0', ' ', '10', ' ', '20', ' ', '30', ' ', '40'};
            app.KnotsSlider.ValueChangedFcn = createCallbackFcn(app, @KnotsSliderValueChanged, true);
            app.KnotsSlider.MinorTicks = [1 2 3 4 5 6 7 8 9 10 11 12 13 14 15 16 17 18 19 20 21 22 23 24 25 26 27 28 29 30 31 32 33 34 35 36 37 38 39 40 41 42 43 44 45 46 47 48 49 50];
            app.KnotsSlider.Tooltip = {'Number of knots of the selected state (first if set to [All]); moving the slider subsitutes equidistant knots'};
            app.KnotsSlider.Value = 20;
            app.FinishButton = uibutton(app.LeftPanel, 'push');
            app.FinishButton.ButtonPushedFcn = createCallbackFcn(app, @FinishButtonPushed, true);
            app.FinishButton.Text = 'Finish';
            app.CellstodisplaySliderLabel = uilabel(app.LeftPanel);
            app.CellstodisplaySliderLabel.Text = {'#Cells to'; 'display'};
            app.CellsSlider = uislider(app.LeftPanel);
            app.CellsSlider.ValueChangedFcn = createCallbackFcn(app, @CellsSliderValueChanged, true);
            app.CellsSlider.Tooltip = {'Number of time series to display'};
            app.StatesDropDownLabel = uilabel(app.LeftPanel);
            app.StatesDropDownLabel.Text = 'State(s)';
            app.StatesDropDown = uidropdown(app.LeftPanel);
            app.StatesDropDown.Items = {'All'};
            app.StatesDropDown.ValueChangedFcn = createCallbackFcn(app, @StateValueChanged, true);
            app.StatesDropDown.Tooltip = {'State(s) to view and edit'};
            app.StatesDropDown.Value = 'All';
            app.AutomaticButton = uibutton(app.LeftPanel, 'push');
            app.AutomaticButton.ButtonPushedFcn = createCallbackFcn(app, @AutomaticButtonPushed, true);
            app.AutomaticButton.Tooltip = {'Replace knots of theselected state(s) using the placement heuristic'};
            app.AutomaticButton.Text = {'Automatic'; 'knot placement'};
            app.ManualknotselectorLabel = uilabel(app.LeftPanel);
            app.ManualknotselectorLabel.Tooltip = {'Knot locations of the selected state (first if set to [All]); left/right clicking adds/removes the closest knot for the selected state(s)'};
            app.ManualknotselectorLabel.Text = 'Manual knot selector';
            app.RightPanel = uipanel(app.GridLayout);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = smoothing_new(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end